classdef PULSE_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        unitgroup_2                     matlab.ui.container.ButtonGroup
        H_LAYmmEditFieldLabel           matlab.ui.control.Label
        H_LAYmmEditField                matlab.ui.control.NumericEditField
        L_LAYmmEditFieldLabel           matlab.ui.control.Label
        L_LAYmmEditField                matlab.ui.control.NumericEditField
        VFRAC_AIR_FRESHSNOWEditFieldLabel  matlab.ui.control.Label
        VFRAC_AIR_FRESHSNOWEditField    matlab.ui.control.NumericEditField
        START_TIMEEditFieldLabel        matlab.ui.control.Label
        START_TIMEEditField             matlab.ui.control.EditField
        PRINT_STEPsecEditFieldLabel     matlab.ui.control.Label
        PRINT_STEPsecEditField          matlab.ui.control.NumericEditField
        A_Dm2sEditFieldLabel            matlab.ui.control.Label
        A_Dm2sEditField                 matlab.ui.control.NumericEditField
        ALPHA_IEEditFieldLabel          matlab.ui.control.Label
        ALPHA_IEEditField               matlab.ui.control.NumericEditField
        COMPFACTOREditFieldLabel        matlab.ui.control.Label
        COMPFACTOREditField             matlab.ui.control.NumericEditField
        QMELT_FILEfullorrelativepathEditFieldLabel  matlab.ui.control.Label
        QMELT_FILEfullorrelativepathEditField  matlab.ui.control.EditField
        METEO_FILEfullorrelativepathEditFieldLabel  matlab.ui.control.Label
        METEO_FILEfullorrelativepathEditField  matlab.ui.control.EditField
        HYDRO_SOLVEREditFieldLabel      matlab.ui.control.Label
        HYDRO_SOLVEREditField           matlab.ui.control.EditField
        DENSITY_ICEkgm3EditFieldLabel   matlab.ui.control.Label
        DENSITY_ICEkgm3EditField        matlab.ui.control.NumericEditField
        DENSITY_WATERkgm3EditFieldLabel  matlab.ui.control.Label
        DENSITY_WATERkgm3EditField      matlab.ui.control.NumericEditField
        DENSITY_FRESHSNOWkgm3EditFieldLabel  matlab.ui.control.Label
        DENSITY_FRESHSNOWkgm3EditField  matlab.ui.control.NumericEditField
        MASTERFILENAMEfullorrelativepathEditFieldLabel  matlab.ui.control.Label
        MASTERFILENAMEfullorrelativepathEditField  matlab.ui.control.EditField
        GENERATEMASTERFILEButton_2      matlab.ui.control.StateButton
        END_TIMEEditFieldLabel          matlab.ui.control.Label
        END_TIMEEditField               matlab.ui.control.EditField
        HYDRO_SOLVEREditFieldLabel_2    matlab.ui.control.Label
        HYDRO_SOLVEREditField_2         matlab.ui.control.EditField
        text13_2                        matlab.ui.control.Label
        unitgroup_3                     matlab.ui.container.ButtonGroup
        H_LAYmmEditField_2Label         matlab.ui.control.Label
        H_LAYmmEditField_2              matlab.ui.control.NumericEditField
        L_LAYmmEditField_2Label         matlab.ui.control.Label
        L_LAYmmEditField_2              matlab.ui.control.NumericEditField
        VFRAC_AIR_FRESHSNOWEditField_2Label  matlab.ui.control.Label
        VFRAC_AIR_FRESHSNOWEditField_2  matlab.ui.control.NumericEditField
        H_SNOWPACKmmEditFieldLabel      matlab.ui.control.Label
        H_SNOWPACKmmEditField           matlab.ui.control.NumericEditField
        L_SNOWPACKmmEditFieldLabel      matlab.ui.control.Label
        L_SNOWPACKmmEditField           matlab.ui.control.NumericEditField
        SnowChemistryfilefullorrelativepathEditFieldLabel  matlab.ui.control.Label
        SnowChemistryfilefullorrelativepathEditField  matlab.ui.control.EditField
        ChemicalspeciesworksheetnameinthefileaboveLabel  matlab.ui.control.Label
        ChemicalspeciesworksheetnameinthefileaboveEditField  matlab.ui.control.EditField
        GENERATEICFILEButton_2          matlab.ui.control.StateButton
        SAVEFILEINFOLDERfullorrelativepathEditFieldLabel  matlab.ui.control.Label
        SAVEFILEINFOLDERfullorrelativepathEditField  matlab.ui.control.EditField
        unitgroup_4                     matlab.ui.container.ButtonGroup
        SnowChemistryfilefullorrelativepathEditField_2Label  matlab.ui.control.Label
        SnowChemistryfilefullorrelativepathEditField_2  matlab.ui.control.EditField
        METEO_FILEfullorrelativepathEditField_2Label  matlab.ui.control.Label
        METEO_FILEfullorrelativepathEditField_2  matlab.ui.control.EditField
        ChemicalspeciesworksheetnameinthefileaboveEditField_2Label  matlab.ui.control.Label
        ChemicalspeciesworksheetnameinthefileaboveEditField_2  matlab.ui.control.EditField
        CRHMoutputfilefullorrelativepathEditFieldLabel_3  matlab.ui.control.Label
        CRHMoutputfilefullorrelativepathEditField  matlab.ui.control.EditField
        GENERATEMETEOFILEButton_3       matlab.ui.control.StateButton
        AirtemperatureEditFieldLabel    matlab.ui.control.Label
        AirtemperatureEditField         matlab.ui.control.EditField
        RHEditFieldLabel                matlab.ui.control.Label
        RHEditField                     matlab.ui.control.EditField
        WindSpeedEditFieldLabel         matlab.ui.control.Label
        WindSpeedEditField              matlab.ui.control.EditField
        QsiEditFieldLabel               matlab.ui.control.Label
        QsiEditField                    matlab.ui.control.EditField
        RainEditFieldLabel              matlab.ui.control.Label
        RainEditField                   matlab.ui.control.EditField
        SnowfallEditFieldLabel          matlab.ui.control.Label
        SnowfallEditField               matlab.ui.control.EditField
        unitgroup_6                     matlab.ui.container.ButtonGroup
        snowmeltevent_field             matlab.ui.control.EditField
        snowmeltcalcmethod1Tindex2CRHMEditField_3Label  matlab.ui.control.Label
        snowmeltcalcmethod1Tindex2CRHMEditField_3  matlab.ui.control.NumericEditField
        T_index_coefonlyusedifsnowmeltcalcmethod1Label  matlab.ui.control.Label
        T_index_coefonlyusedifsnowmeltcalcmethod1EditField  matlab.ui.control.NumericEditField
        QMELT_FILEfullorrelativepathEditField_2Label  matlab.ui.control.Label
        QMELT_FILEfullorrelativepathEditField_2  matlab.ui.control.EditField
        CRHMoutputfilefullorrelativepathEditFieldLabel  matlab.ui.control.Label
        GENERATEQMELTFILEButton         matlab.ui.control.StateButton
        CRHMoutputfilefullorrelativepathifsnowmeltcalcmethod2EditField  matlab.ui.control.EditField
        CRHMoutputfilefullorrelativepathEditFieldLabel_2  matlab.ui.control.Label
        GENERATEALLFILESButton          matlab.ui.control.StateButton
        unitgroup_7                     matlab.ui.container.ButtonGroup
        text13_3                        matlab.ui.control.Label
        Directoryofpulse_cppexecutableEditFieldLabel  matlab.ui.control.Label
        Directoryofpulse_cppexecutableEditField  matlab.ui.control.EditField
        uipanel1                        matlab.ui.container.Panel
        PlotresultssinglerunCheckBox    matlab.ui.control.CheckBox
        runsimulationCheckBox           matlab.ui.control.CheckBox
        deleteResultsfolderexceptICfileCheckBox  matlab.ui.control.CheckBox
        text2                           matlab.ui.control.Label
        edit3                           matlab.ui.control.EditField
        uipanel1_2                      matlab.ui.container.Panel
        scenariosEditFieldLabel         matlab.ui.control.Label
        scenariosEditField              matlab.ui.control.NumericEditField
        A_D_maxEditFieldLabel           matlab.ui.control.Label
        A_D_maxEditField                matlab.ui.control.NumericEditField
        ALPHA_IE_maxEditFieldLabel      matlab.ui.control.Label
        ALPHA_IE_maxEditField           matlab.ui.control.NumericEditField
        RuntestsCheckBox                matlab.ui.control.CheckBox
        AnalyzeresultsCheckBox          matlab.ui.control.CheckBox
        PlotSensresultsCheckBox         matlab.ui.control.CheckBox
        RUNButton                       matlab.ui.control.StateButton
        Lamp_2                          matlab.ui.control.Lamp
        RUNButton_2                     matlab.ui.control.StateButton
        RUNsinglesimulationGauge_2Label  matlab.ui.control.Label
        RunningscenariosGaugeLabel      matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        ProcessingresultsGaugeLabel     matlab.ui.control.Label
        ProcessingresultsGauge          matlab.ui.control.LinearGauge
        Lamp_4                          matlab.ui.control.Lamp
        PlottingresultsGauge_2Label     matlab.ui.control.Label
        PlottingresultsGauge_2          matlab.ui.control.LinearGauge
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        RunningsimulationGaugeLabel     matlab.ui.control.Label
        RunningsimulationGauge          matlab.ui.control.LinearGauge
        CalcsnowpackdepthGaugeLabel     matlab.ui.control.Label
        CalcsnowpackdepthGauge          matlab.ui.control.LinearGauge
        LoadingResultsGaugeLabel        matlab.ui.control.Label
        LoadingResultsGauge             matlab.ui.control.LinearGauge
        Lamp_8                          matlab.ui.control.Lamp
        PlottingresultsGaugeLabel       matlab.ui.control.Label
        PlottingresultsGauge            matlab.ui.control.LinearGauge
        RunningscenariosGauge           matlab.ui.control.LinearGauge
        newfilehasbeengeneratedLamp_4Label  matlab.ui.control.Label
        newfilehasbeengeneratedLamp_4   matlab.ui.control.Lamp
        newfilehasbeengeneratedLamp_3Label  matlab.ui.control.Label
        newfilehasbeengeneratedLamp_3   matlab.ui.control.Lamp
        newfilehasbeengeneratedLamp_2Label  matlab.ui.control.Label
        newfilehasbeengeneratedLamp_2   matlab.ui.control.Lamp
        newfilehasbeengeneratedLampLabel  matlab.ui.control.Label
        newfilehasbeengeneratedLamp     matlab.ui.control.Lamp
    end

    
    methods (Access = private)
        function density_Callback(app, hObject, eventdata, handles)
            % hObject    handle to density (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of density as text
            %        str2double(get(hObject,'String')) returns contents of density as a double
            density = str2double(get(hObject, 'String'));
            if isnan(density)
                set(hObject, 'String', 0);
                errordlg('Input must be a number','Error');
            end
            
            % Save the new density value
            app.metricdata.density = density;
            guidata(hObject,handles)
        end
        
        function density_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to density (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit12_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit7_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit8_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function initialize_gui(app, fig_handle, handles, isreset)
            % If the metricdata field is present and the reset flag is false, it means
            % we are we are just re-initializing a GUI by calling it from the cmd line
            % while it is up. So, bail out as we dont want to reset the data.
            if isfield(handles, 'metricdata') && ~isreset
                return;
            end
            
            app.metricdata.density = 0;
            app.metricdata.volume  = 0;
            
            set(app.density, 'String', app.metricdata.density);
            set(app.volume,  'String', app.metricdata.volume);
            set(app.mass, 'String', 0);
            
            set(app.unitgroup, 'SelectedObject', app.english);
            
            set(app.text4, 'String', 'lb/cu.in');
            set(app.text5, 'String', 'cu.in');
            set(app.text6, 'String', 'lb');
            
            % Update handles structure
            guidata(app.figure1, handles);
        end
        
        function volume_Callback(app, hObject, eventdata, handles)
            % hObject    handle to volume (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of volume as text
            %        str2double(get(hObject,'String')) returns contents of volume as a double
            volume = str2double(get(hObject, 'String'));
            if isnan(volume)
                set(hObject, 'String', 0);
                errordlg('Input must be a number','Error');
            end
            
            % Save the new volume value
            app.metricdata.volume = volume;
            guidata(hObject,handles)
        end
        
        function volume_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to volume (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PULSE_GUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to PULSE_GUI (see VARARGIN)
%             
%             % Choose default command line output for PULSE_GUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             initialize_gui(hObject, handles, false);
        end

        % Callback function
        function reset_Callback(app, event)
            % hObject    handle to reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            initialize_gui(gcbf, handles, true);
        end

        % Callback function
        function unitgroup_SelectionChangedFcn(app, event)
            % hObject    handle to the selected object in unitgroup
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if (hObject == app.english)
                set(app.text4, 'String', 'lb/cu.in');
                set(app.text5, 'String', 'cu.in');
                set(app.text6, 'String', 'lb');
            else
                set(app.text4, 'String', 'kg/cu.m');
                set(app.text5, 'String', 'cu.m');
                set(app.text6, 'String', 'kg');
            end
        end

        % Callback function
        function RUNButtonPushed(app, event)
            while(app.statebutton.Value ~=1) 
                pause(0.2);
            end
        end

        % Callback function
        function RUNButtonValueChanged(app, event)
            value = app.RUNButton.Value;
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Single RUN')
                app.newfilehasbeengeneratedLamp.Color = 'green';
                app.Lamp_2.Color = 'red';
            else
                app.Lamp_2.Color = 'green';
                app.newfilehasbeengeneratedLamp.Color = 'red';
            end
            drawnow
        end

        % Callback function
        function GENERATEMASTERFILEButtonPushed(app, event)
            app.GENERATEMETEOFILEButton.ButtonPushedFcn.RUN = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'PULSE GUI';
            app.unitgroup_2 = uibuttongroup(app.figure1);
            app.unitgroup_2.Title = 'MODEL SETUP (master file)';
            app.H_LAYmmEditFieldLabel = uilabel(app.unitgroup_2);
            app.H_LAYmmEditFieldLabel.Text = 'H_LAY (mm)';
            app.H_LAYmmEditField = uieditfield(app.unitgroup_2, 'numeric');
            app.H_LAYmmEditField.Value = 10;
            app.L_LAYmmEditFieldLabel = uilabel(app.unitgroup_2);
            app.L_LAYmmEditFieldLabel.Text = 'L_LAY (mm)';
            app.L_LAYmmEditField = uieditfield(app.unitgroup_2, 'numeric');
            app.L_LAYmmEditField.Value = 10;
            app.VFRAC_AIR_FRESHSNOWEditFieldLabel = uilabel(app.unitgroup_2);
            app.VFRAC_AIR_FRESHSNOWEditFieldLabel.Text = 'VFRAC_AIR_FRESHSNOW';
            app.VFRAC_AIR_FRESHSNOWEditField = uieditfield(app.unitgroup_2, 'numeric');
            app.VFRAC_AIR_FRESHSNOWEditField.Value = 0.05;
            app.START_TIMEEditFieldLabel = uilabel(app.unitgroup_2);
            app.START_TIMEEditFieldLabel.Text = 'START_TIME';
            app.START_TIMEEditField = uieditfield(app.unitgroup_2, 'text');
            app.START_TIMEEditField.Value = '25-03-2015 00:00:00';
            app.PRINT_STEPsecEditFieldLabel = uilabel(app.unitgroup_2);
            app.PRINT_STEPsecEditFieldLabel.Text = 'PRINT_STEP (sec)';
            app.PRINT_STEPsecEditField = uieditfield(app.unitgroup_2, 'numeric');
            app.PRINT_STEPsecEditField.Value = 3600;
            app.A_Dm2sEditFieldLabel = uilabel(app.unitgroup_2);
            app.A_Dm2sEditFieldLabel.Text = 'A_D (m2/s)';
            app.A_Dm2sEditField = uieditfield(app.unitgroup_2, 'numeric');
            app.A_Dm2sEditField.Value = 3.453e-07;
            app.ALPHA_IEEditFieldLabel = uilabel(app.unitgroup_2);
            app.ALPHA_IEEditFieldLabel.Text = 'ALPHA_IE';
            app.ALPHA_IEEditField = uieditfield(app.unitgroup_2, 'numeric');
            app.ALPHA_IEEditField.Value = 1.998e-06;
            app.COMPFACTOREditFieldLabel = uilabel(app.unitgroup_2);
            app.COMPFACTOREditFieldLabel.Text = 'COMPFACTOR';
            app.COMPFACTOREditField = uieditfield(app.unitgroup_2, 'numeric');
            app.COMPFACTOREditField.Value = 0.0055;
            app.QMELT_FILEfullorrelativepathEditFieldLabel = uilabel(app.unitgroup_2);
            app.QMELT_FILEfullorrelativepathEditFieldLabel.Text = 'QMELT_FILE (full or relative path)';
            app.QMELT_FILEfullorrelativepathEditField = uieditfield(app.unitgroup_2, 'text');
            app.QMELT_FILEfullorrelativepathEditField.Value = 'bin/qcmelt_svalbard_test_crhm.txt';
            app.METEO_FILEfullorrelativepathEditFieldLabel = uilabel(app.unitgroup_2);
            app.METEO_FILEfullorrelativepathEditFieldLabel.Text = 'METEO_FILE (full or relative path)';
            app.METEO_FILEfullorrelativepathEditField = uieditfield(app.unitgroup_2, 'text');
            app.METEO_FILEfullorrelativepathEditField.Value = 'bin/meteo_svalbard_test.txt';
            app.HYDRO_SOLVEREditFieldLabel = uilabel(app.unitgroup_2);
            app.HYDRO_SOLVEREditFieldLabel.Text = 'HYDRO_SOLVER';
            app.HYDRO_SOLVEREditField = uieditfield(app.unitgroup_2, 'text');
            app.HYDRO_SOLVEREditField.Value = 'simplified';
            app.DENSITY_ICEkgm3EditFieldLabel = uilabel(app.unitgroup_2);
            app.DENSITY_ICEkgm3EditFieldLabel.Text = 'DENSITY_ICE (kg/m3)';
            app.DENSITY_ICEkgm3EditField = uieditfield(app.unitgroup_2, 'numeric');
            app.DENSITY_ICEkgm3EditField.Value = 917;
            app.DENSITY_WATERkgm3EditFieldLabel = uilabel(app.unitgroup_2);
            app.DENSITY_WATERkgm3EditFieldLabel.Text = 'DENSITY_WATER (kg/m3)';
            app.DENSITY_WATERkgm3EditField = uieditfield(app.unitgroup_2, 'numeric');
            app.DENSITY_WATERkgm3EditField.Value = 998.8;
            app.DENSITY_FRESHSNOWkgm3EditFieldLabel = uilabel(app.unitgroup_2);
            app.DENSITY_FRESHSNOWkgm3EditFieldLabel.Text = 'DENSITY_FRESHSNOW (kg/m3)';
            app.DENSITY_FRESHSNOWkgm3EditField = uieditfield(app.unitgroup_2, 'numeric');
            app.DENSITY_FRESHSNOWkgm3EditField.Value = 320;
            app.MASTERFILENAMEfullorrelativepathEditFieldLabel = uilabel(app.unitgroup_2);
            app.MASTERFILENAMEfullorrelativepathEditFieldLabel.Text = 'MASTER FILE NAME (full or relative path)';
            app.MASTERFILENAMEfullorrelativepathEditField = uieditfield(app.unitgroup_2, 'text');
            app.MASTERFILENAMEfullorrelativepathEditField.Value = 'bin/simset.txt';
            app.GENERATEMASTERFILEButton_2 = uibutton(app.unitgroup_2, 'state');
            app.GENERATEMASTERFILEButton_2.Text = 'GENERATE MASTER FILE';
            app.END_TIMEEditFieldLabel = uilabel(app.unitgroup_2);
            app.END_TIMEEditFieldLabel.Text = 'END_TIME';
            app.END_TIMEEditField = uieditfield(app.unitgroup_2, 'text');
            app.END_TIMEEditField.Value = '31-08-2015 00:00:00';
            app.HYDRO_SOLVEREditFieldLabel_2 = uilabel(app.unitgroup_2);
            app.HYDRO_SOLVEREditFieldLabel_2.Text = 'COMMNET ';
            app.HYDRO_SOLVEREditField_2 = uieditfield(app.unitgroup_2, 'text');
            app.HYDRO_SOLVEREditField_2.Value = 'Tests: SVALBARD';
            app.text13_2 = uilabel(app.figure1);
            app.text13_2.Text = '';
            app.unitgroup_3 = uibuttongroup(app.figure1);
            app.unitgroup_3.Title = 'INITIAL CONDITIONS (0.txt file)';
            app.H_LAYmmEditField_2Label = uilabel(app.unitgroup_3);
            app.H_LAYmmEditField_2Label.Text = 'H_LAY (mm)';
            app.H_LAYmmEditField_2 = uieditfield(app.unitgroup_3, 'numeric');
            app.H_LAYmmEditField_2.Editable = 'off';
            app.H_LAYmmEditField_2.Value = 10;
            app.L_LAYmmEditField_2Label = uilabel(app.unitgroup_3);
            app.L_LAYmmEditField_2Label.Text = 'L_LAY (mm)';
            app.L_LAYmmEditField_2 = uieditfield(app.unitgroup_3, 'numeric');
            app.L_LAYmmEditField_2.Editable = 'off';
            app.L_LAYmmEditField_2.Value = 10;
            app.VFRAC_AIR_FRESHSNOWEditField_2Label = uilabel(app.unitgroup_3);
            app.VFRAC_AIR_FRESHSNOWEditField_2Label.Text = 'VFRAC_AIR_FRESHSNOW';
            app.VFRAC_AIR_FRESHSNOWEditField_2 = uieditfield(app.unitgroup_3, 'numeric');
            app.VFRAC_AIR_FRESHSNOWEditField_2.Editable = 'off';
            app.VFRAC_AIR_FRESHSNOWEditField_2.Value = 0.05;
            app.H_SNOWPACKmmEditFieldLabel = uilabel(app.unitgroup_3);
            app.H_SNOWPACKmmEditFieldLabel.Text = 'H_SNOWPACK (mm)';
            app.H_SNOWPACKmmEditField = uieditfield(app.unitgroup_3, 'numeric');
            app.H_SNOWPACKmmEditField.Value = 1000;
            app.L_SNOWPACKmmEditFieldLabel = uilabel(app.unitgroup_3);
            app.L_SNOWPACKmmEditFieldLabel.Text = 'L_SNOWPACK (mm)';
            app.L_SNOWPACKmmEditField = uieditfield(app.unitgroup_3, 'numeric');
            app.L_SNOWPACKmmEditField.Value = 100;
            app.SnowChemistryfilefullorrelativepathEditFieldLabel = uilabel(app.unitgroup_3);
            app.SnowChemistryfilefullorrelativepathEditFieldLabel.Text = 'Snow Chemistry file (full or relative path)';
            app.SnowChemistryfilefullorrelativepathEditField = uieditfield(app.unitgroup_3, 'text');
            app.SnowChemistryfilefullorrelativepathEditField.Value = 'BRG_data.xlsx';
            app.ChemicalspeciesworksheetnameinthefileaboveLabel = uilabel(app.unitgroup_3);
            app.ChemicalspeciesworksheetnameinthefileaboveLabel.Text = {'Chemical species'; '(worksheet name in the file above)'};
            app.ChemicalspeciesworksheetnameinthefileaboveEditField = uieditfield(app.unitgroup_3, 'text');
            app.ChemicalspeciesworksheetnameinthefileaboveEditField.Value = 'NO3';
            app.GENERATEICFILEButton_2 = uibutton(app.unitgroup_3, 'state');
            app.GENERATEICFILEButton_2.Text = 'GENERATE IC FILE';
            app.SAVEFILEINFOLDERfullorrelativepathEditFieldLabel = uilabel(app.unitgroup_3);
            app.SAVEFILEINFOLDERfullorrelativepathEditFieldLabel.Text = 'SAVE FILE IN FOLDER (full or relative path)';
            app.SAVEFILEINFOLDERfullorrelativepathEditField = uieditfield(app.unitgroup_3, 'text');
            app.SAVEFILEINFOLDERfullorrelativepathEditField.Value = 'bin/Results';
            app.unitgroup_4 = uibuttongroup(app.figure1);
            app.unitgroup_4.Title = 'METEO FILE';
            app.SnowChemistryfilefullorrelativepathEditField_2Label = uilabel(app.unitgroup_4);
            app.SnowChemistryfilefullorrelativepathEditField_2Label.Text = 'Snow Chemistry file (full or relative path)';
            app.SnowChemistryfilefullorrelativepathEditField_2 = uieditfield(app.unitgroup_4, 'text');
            app.SnowChemistryfilefullorrelativepathEditField_2.Editable = 'off';
            app.SnowChemistryfilefullorrelativepathEditField_2.Value = 'BRG_data.xlsx';
            app.METEO_FILEfullorrelativepathEditField_2Label = uilabel(app.unitgroup_4);
            app.METEO_FILEfullorrelativepathEditField_2Label.Text = 'METEO_FILE (full or relative path)';
            app.METEO_FILEfullorrelativepathEditField_2 = uieditfield(app.unitgroup_4, 'text');
            app.METEO_FILEfullorrelativepathEditField_2.Editable = 'off';
            app.METEO_FILEfullorrelativepathEditField_2.Value = 'bin/meteo_svalbard_test.txt';
            app.ChemicalspeciesworksheetnameinthefileaboveEditField_2Label = uilabel(app.unitgroup_4);
            app.ChemicalspeciesworksheetnameinthefileaboveEditField_2Label.Text = {'Chemical species'; '(worksheet name in the file above)'};
            app.ChemicalspeciesworksheetnameinthefileaboveEditField_2 = uieditfield(app.unitgroup_4, 'text');
            app.ChemicalspeciesworksheetnameinthefileaboveEditField_2.Value = 'NO3';
            app.CRHMoutputfilefullorrelativepathEditFieldLabel_3 = uilabel(app.unitgroup_4);
            app.CRHMoutputfilefullorrelativepathEditFieldLabel_3.Text = 'CRHM outputfile (full or relative path)';
            app.CRHMoutputfilefullorrelativepathEditField = uieditfield(app.unitgroup_4, 'text');
            app.CRHMoutputfilefullorrelativepathEditField.Value = 'CRHM_output_1.txt';
            app.GENERATEMETEOFILEButton_3 = uibutton(app.unitgroup_4, 'state');
            app.GENERATEMETEOFILEButton_3.Text = 'GENERATE METEO FILE';
            app.AirtemperatureEditFieldLabel = uilabel(app.unitgroup_4);
            app.AirtemperatureEditFieldLabel.Text = 'Air temperature';
            app.AirtemperatureEditField = uieditfield(app.unitgroup_4, 'text');
            app.AirtemperatureEditField.Value = 'hru_t(1)';
            app.RHEditFieldLabel = uilabel(app.unitgroup_4);
            app.RHEditFieldLabel.Text = 'RH';
            app.RHEditField = uieditfield(app.unitgroup_4, 'text');
            app.RHEditField.Value = 'hru_rh(1)';
            app.WindSpeedEditFieldLabel = uilabel(app.unitgroup_4);
            app.WindSpeedEditFieldLabel.Text = 'Wind Speed';
            app.WindSpeedEditField = uieditfield(app.unitgroup_4, 'text');
            app.WindSpeedEditField.Value = 'hru_u(1)';
            app.QsiEditFieldLabel = uilabel(app.unitgroup_4);
            app.QsiEditFieldLabel.Text = 'Qsi';
            app.QsiEditField = uieditfield(app.unitgroup_4, 'text');
            app.QsiEditField.Value = 'QsiD_Var(1)';
            app.RainEditFieldLabel = uilabel(app.unitgroup_4);
            app.RainEditFieldLabel.Text = 'Rain';
            app.RainEditField = uieditfield(app.unitgroup_4, 'text');
            app.RainEditField.Value = 'hru_rain(1)';
            app.SnowfallEditFieldLabel = uilabel(app.unitgroup_4);
            app.SnowfallEditFieldLabel.Text = 'Snowfall';
            app.SnowfallEditField = uieditfield(app.unitgroup_4, 'text');
            app.SnowfallEditField.Value = 'hru_snow(1)';
            app.unitgroup_6 = uibuttongroup(app.figure1);
            app.unitgroup_6.Title = 'QMELT FILE';
            app.snowmeltevent_field = uieditfield(app.unitgroup_6, 'text');
            app.snowmeltevent_field.Value = 'snowmelt_int(2)';
            app.snowmeltcalcmethod1Tindex2CRHMEditField_3Label = uilabel(app.unitgroup_6);
            app.snowmeltcalcmethod1Tindex2CRHMEditField_3Label.Text = {'snowmelt calc method'; '(1) T-index, (2) CRHM'};
            app.snowmeltcalcmethod1Tindex2CRHMEditField_3 = uieditfield(app.unitgroup_6, 'numeric');
            app.snowmeltcalcmethod1Tindex2CRHMEditField_3.Value = 2;
            app.T_index_coefonlyusedifsnowmeltcalcmethod1Label = uilabel(app.unitgroup_6);
            app.T_index_coefonlyusedifsnowmeltcalcmethod1Label.Text = {'T_index_coef'; '(only used if snowmelt'; ' calc method = 1) '};
            app.T_index_coefonlyusedifsnowmeltcalcmethod1EditField = uieditfield(app.unitgroup_6, 'numeric');
            app.T_index_coefonlyusedifsnowmeltcalcmethod1EditField.Value = 10;
            app.QMELT_FILEfullorrelativepathEditField_2Label = uilabel(app.unitgroup_6);
            app.QMELT_FILEfullorrelativepathEditField_2Label.Text = 'QMELT_FILE (full or relative path)';
            app.QMELT_FILEfullorrelativepathEditField_2 = uieditfield(app.unitgroup_6, 'text');
            app.QMELT_FILEfullorrelativepathEditField_2.Editable = 'off';
            app.QMELT_FILEfullorrelativepathEditField_2.Value = 'bin/qcmelt_svalbard_test_crhm.txt';
            app.CRHMoutputfilefullorrelativepathEditFieldLabel = uilabel(app.unitgroup_6);
            app.CRHMoutputfilefullorrelativepathEditFieldLabel.Text = {'CRHM outputfile (full or relative path)'; '(only used if snowmelt calc method = 2)'};
            app.GENERATEQMELTFILEButton = uibutton(app.unitgroup_6, 'state');
            app.GENERATEQMELTFILEButton.Text = 'GENERATE QMELT FILE';
            app.CRHMoutputfilefullorrelativepathifsnowmeltcalcmethod2EditField = uieditfield(app.unitgroup_6, 'text');
            app.CRHMoutputfilefullorrelativepathifsnowmeltcalcmethod2EditField.Editable = 'off';
            app.CRHMoutputfilefullorrelativepathifsnowmeltcalcmethod2EditField.Value = 'CRHM_output_1.txt';
            app.CRHMoutputfilefullorrelativepathEditFieldLabel_2 = uilabel(app.unitgroup_6);
            app.CRHMoutputfilefullorrelativepathEditFieldLabel_2.Text = {'Snowmelt name in the CRHM outputfile'; '(only used if snowmelt calc method = 2)'};
            app.GENERATEALLFILESButton = uibutton(app.figure1, 'state');
            app.GENERATEALLFILESButton.Text = 'GENERATE ALL FILES';
            app.unitgroup_7 = uibuttongroup(app.figure1);
            app.unitgroup_7.Title = 'SIMULATION (general info)';
            app.text13_3 = uilabel(app.unitgroup_7);
            app.text13_3.Text = '';
            app.Directoryofpulse_cppexecutableEditFieldLabel = uilabel(app.unitgroup_7);
            app.Directoryofpulse_cppexecutableEditFieldLabel.Text = 'Directory of pulse_cpp executable';
            app.Directoryofpulse_cppexecutableEditField = uieditfield(app.unitgroup_7, 'text');
            app.Directoryofpulse_cppexecutableEditField.Value = 'bin';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'SINGLE RUN';
            app.PlotresultssinglerunCheckBox = uicheckbox(app.uipanel1);
            app.PlotresultssinglerunCheckBox.Text = ' Plot results (single run)';
            app.PlotresultssinglerunCheckBox.Value = true;
            app.runsimulationCheckBox = uicheckbox(app.uipanel1);
            app.runsimulationCheckBox.Text = ' run simulation';
            app.runsimulationCheckBox.Value = true;
            app.deleteResultsfolderexceptICfileCheckBox = uicheckbox(app.uipanel1);
            app.deleteResultsfolderexceptICfileCheckBox.Text = ' delete Results folder (except IC file)';
            app.deleteResultsfolderexceptICfileCheckBox.Value = true;
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'IC file (*.txt):';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Value = '0.txt';
            app.uipanel1_2 = uipanel(app.figure1);
            app.uipanel1_2.Title = 'SENSITIVITY ANALYSIS';
            app.scenariosEditFieldLabel = uilabel(app.uipanel1_2);
            app.scenariosEditFieldLabel.Text = '# scenarios';
            app.scenariosEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.scenariosEditField.Value = 200;
            app.A_D_maxEditFieldLabel = uilabel(app.uipanel1_2);
            app.A_D_maxEditFieldLabel.Text = 'A_D_max';
            app.A_D_maxEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.A_D_maxEditField.Value = 0.0003;
            app.ALPHA_IE_maxEditFieldLabel = uilabel(app.uipanel1_2);
            app.ALPHA_IE_maxEditFieldLabel.Text = 'ALPHA_IE_max';
            app.ALPHA_IE_maxEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.ALPHA_IE_maxEditField.Value = 3e-06;
            app.RuntestsCheckBox = uicheckbox(app.uipanel1_2);
            app.RuntestsCheckBox.Text = ' Run tests';
            app.RuntestsCheckBox.Value = true;
            app.AnalyzeresultsCheckBox = uicheckbox(app.uipanel1_2);
            app.AnalyzeresultsCheckBox.Text = 'Analyze results';
            app.AnalyzeresultsCheckBox.Value = true;
            app.PlotSensresultsCheckBox = uicheckbox(app.uipanel1_2);
            app.PlotSensresultsCheckBox.Text = 'Plot Sens results';
            app.PlotSensresultsCheckBox.Value = true;
            app.RUNButton = uibutton(app.figure1, 'state');
            app.RUNButton.Text = 'RUN';
            app.Lamp_2 = uilamp(app.figure1);
            app.RUNButton_2 = uibutton(app.figure1, 'state');
            app.RUNButton_2.Text = 'RUN';
            app.RUNsinglesimulationGauge_2Label = uilabel(app.figure1);
            app.RUNsinglesimulationGauge_2Label.Text = 'RUN single simulation';
            app.RunningscenariosGaugeLabel = uilabel(app.figure1);
            app.RunningscenariosGaugeLabel.Text = 'Running scenarios';
            app.Lamp_3 = uilamp(app.figure1);
            app.ProcessingresultsGaugeLabel = uilabel(app.figure1);
            app.ProcessingresultsGaugeLabel.Text = 'Processing results';
            app.ProcessingresultsGauge = uigauge(app.figure1, 'linear');
            app.Lamp_4 = uilamp(app.figure1);
            app.PlottingresultsGauge_2Label = uilabel(app.figure1);
            app.PlottingresultsGauge_2Label.Text = 'Plotting results';
            app.PlottingresultsGauge_2 = uigauge(app.figure1, 'linear');
            app.Lamp_5 = uilamp(app.figure1);
            app.Lamp_6 = uilamp(app.figure1);
            app.Lamp_7 = uilamp(app.figure1);
            app.RunningsimulationGaugeLabel = uilabel(app.figure1);
            app.RunningsimulationGaugeLabel.Text = 'Running simulation';
            app.RunningsimulationGauge = uigauge(app.figure1, 'linear');
            app.CalcsnowpackdepthGaugeLabel = uilabel(app.figure1);
            app.CalcsnowpackdepthGaugeLabel.Text = 'Calc. snowpack depth';
            app.CalcsnowpackdepthGauge = uigauge(app.figure1, 'linear');
            app.LoadingResultsGaugeLabel = uilabel(app.figure1);
            app.LoadingResultsGaugeLabel.Text = 'Loading Results';
            app.LoadingResultsGauge = uigauge(app.figure1, 'linear');
            app.Lamp_8 = uilamp(app.figure1);
            app.PlottingresultsGaugeLabel = uilabel(app.figure1);
            app.PlottingresultsGaugeLabel.Text = 'Plotting results';
            app.PlottingresultsGauge = uigauge(app.figure1, 'linear');
            app.RunningscenariosGauge = uigauge(app.figure1, 'linear');
            app.newfilehasbeengeneratedLamp_4Label = uilabel(app.figure1);
            app.newfilehasbeengeneratedLamp_4Label.Text = 'new file has been generated';
            app.newfilehasbeengeneratedLamp_4 = uilamp(app.figure1);
            app.newfilehasbeengeneratedLamp_3Label = uilabel(app.figure1);
            app.newfilehasbeengeneratedLamp_3Label.Text = 'new file has been generated';
            app.newfilehasbeengeneratedLamp_3 = uilamp(app.figure1);
            app.newfilehasbeengeneratedLamp_2Label = uilabel(app.figure1);
            app.newfilehasbeengeneratedLamp_2Label.Text = 'new file has been generated';
            app.newfilehasbeengeneratedLamp_2 = uilamp(app.figure1);
            app.newfilehasbeengeneratedLampLabel = uilabel(app.figure1);
            app.newfilehasbeengeneratedLampLabel.Text = 'new file has been generated';
            app.newfilehasbeengeneratedLamp = uilamp(app.figure1);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PULSE_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @PULSE_GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end