classdef PULSE_GUI_run < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        unitgroup                       matlab.ui.container.ButtonGroup
        text13                          matlab.ui.control.Label
        WorkingdirectoryEditField_2Label  matlab.ui.control.Label
        WorkingdirectoryEditField_2     matlab.ui.control.EditField
        MasterfilenameEditFieldLabel    matlab.ui.control.Label
        MasterfilenameEditField         matlab.ui.control.EditField
        ResultsdirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsdirectoryEditField       matlab.ui.control.EditField
        ObservationfilefullpathEditFieldLabel  matlab.ui.control.Label
        ObservationfilefullpathEditField  matlab.ui.control.EditField
        ChemicalspeciesEditFieldLabel   matlab.ui.control.Label
        ChemicalspeciesEditField        matlab.ui.control.EditField
        uipanel1                        matlab.ui.container.Panel
        text2                           matlab.ui.control.Label
        edit3                           matlab.ui.control.EditField
        PlotresultssinglerunCheckBox    matlab.ui.control.CheckBox
        runsimulationCheckBox           matlab.ui.control.CheckBox
        deleteResultsfolderexceptICfileCheckBox  matlab.ui.control.CheckBox
        OPTIONSButtonGroup              matlab.ui.container.ButtonGroup
        Switch                          matlab.ui.control.Switch
        uipanel1_2                      matlab.ui.container.Panel
        scenariosEditFieldLabel         matlab.ui.control.Label
        scenariosEditField              matlab.ui.control.NumericEditField
        A_D_maxEditFieldLabel           matlab.ui.control.Label
        A_D_maxEditField                matlab.ui.control.NumericEditField
        ALPHA_IE_maxEditFieldLabel      matlab.ui.control.Label
        ALPHA_IE_maxEditField           matlab.ui.control.NumericEditField
        RuntestsCheckBox                matlab.ui.control.CheckBox
        AnalyzeresultsCheckBox          matlab.ui.control.CheckBox
        PlotSensresultsCheckBox         matlab.ui.control.CheckBox
        RUNButton                       matlab.ui.control.StateButton
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
    end

    
    methods (Access = private)
        function density_Callback(app, hObject, eventdata, handles)
            % hObject    handle to density (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of density as text
            %        str2double(get(hObject,'String')) returns contents of density as a double
            density = str2double(get(hObject, 'String'));
            if isnan(density)
                set(hObject, 'String', 0);
                errordlg('Input must be a number','Error');
            end
            
            % Save the new density value
            app.metricdata.density = density;
            guidata(hObject,handles)
        end
        
        function density_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to density (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit12_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit7_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit8_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function initialize_gui(app, fig_handle, handles, isreset)
            % If the metricdata field is present and the reset flag is false, it means
            % we are we are just re-initializing a GUI by calling it from the cmd line
            % while it is up. So, bail out as we dont want to reset the data.
            if isfield(handles, 'metricdata') && ~isreset
                return;
            end
            
            app.metricdata.density = 0;
            app.metricdata.volume  = 0;
            
            set(app.density, 'String', app.metricdata.density);
            set(app.volume,  'String', app.metricdata.volume);
            set(app.mass, 'String', 0);
            
            set(app.unitgroup, 'SelectedObject', app.english);
            
            set(app.text4, 'String', 'lb/cu.in');
            set(app.text5, 'String', 'cu.in');
            set(app.text6, 'String', 'lb');
            
            % Update handles structure
            guidata(app.figure1, handles);
        end
        
        function volume_Callback(app, hObject, eventdata, handles)
            % hObject    handle to volume (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of volume as text
            %        str2double(get(hObject,'String')) returns contents of volume as a double
            volume = str2double(get(hObject, 'String'));
            if isnan(volume)
                set(hObject, 'String', 0);
                errordlg('Input must be a number','Error');
            end
            
            % Save the new volume value
            app.metricdata.volume = volume;
            guidata(hObject,handles)
        end
        
        function volume_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to volume (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PULSE_GUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to PULSE_GUI (see VARARGIN)
%             
%             % Choose default command line output for PULSE_GUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             initialize_gui(hObject, handles, false);
        end

        % Callback function
        function reset_Callback(app, event)
            % hObject    handle to reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            initialize_gui(gcbf, handles, true);
        end

        % Selection changed function: unitgroup
        function unitgroup_SelectionChangedFcn(app, event)
            % hObject    handle to the selected object in unitgroup
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if (hObject == app.english)
                set(app.text4, 'String', 'lb/cu.in');
                set(app.text5, 'String', 'cu.in');
                set(app.text6, 'String', 'lb');
            else
                set(app.text4, 'String', 'kg/cu.m');
                set(app.text5, 'String', 'cu.m');
                set(app.text6, 'String', 'kg');
            end
        end

        % Callback function
        function RUNButtonPushed(app, event)
            while(app.statebutton.Value ~=1) 
                pause(0.2);
            end
        end

        % Callback function
        function RUNButtonValueChanged(app, event)
            value = app.RUNButton.Value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Single RUN')
                app.Lamp.Color = 'green';
                app.Lamp_2.Color = 'red';
            else
                app.Lamp_2.Color = 'green';
                app.Lamp.Color = 'red';
            end
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'PULSE GUI (support)';
            app.unitgroup = uibuttongroup(app.figure1);
            app.unitgroup.SelectionChangedFcn = createCallbackFcn(app, @unitgroup_SelectionChangedFcn, true);
            app.unitgroup.Title = 'GENERAL INFO';
            app.text13 = uilabel(app.unitgroup);
            app.text13.Text = '';
            app.WorkingdirectoryEditField_2Label = uilabel(app.unitgroup);
            app.WorkingdirectoryEditField_2Label.Text = 'Working directory';
            app.WorkingdirectoryEditField_2 = uieditfield(app.unitgroup, 'text');
            app.WorkingdirectoryEditField_2.Value = 'bin';
            app.MasterfilenameEditFieldLabel = uilabel(app.unitgroup);
            app.MasterfilenameEditFieldLabel.Text = 'Masterfile name';
            app.MasterfilenameEditField = uieditfield(app.unitgroup, 'text');
            app.MasterfilenameEditField.Value = 'simset.txt';
            app.ResultsdirectoryEditFieldLabel = uilabel(app.unitgroup);
            app.ResultsdirectoryEditFieldLabel.Text = 'Results directory';
            app.ResultsdirectoryEditField = uieditfield(app.unitgroup, 'text');
            app.ResultsdirectoryEditField.Value = 'bin/Results';
            app.ObservationfilefullpathEditFieldLabel = uilabel(app.unitgroup);
            app.ObservationfilefullpathEditFieldLabel.Text = 'Observation file (full path)';
            app.ObservationfilefullpathEditField = uieditfield(app.unitgroup, 'text');
            app.ObservationfilefullpathEditField.Value = 'BRG_data.xlsx';
            app.ChemicalspeciesEditFieldLabel = uilabel(app.unitgroup);
            app.ChemicalspeciesEditFieldLabel.Text = 'Chemical species';
            app.ChemicalspeciesEditField = uieditfield(app.unitgroup, 'text');
            app.ChemicalspeciesEditField.Value = 'NO3';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'if SINGLE RUN';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'IC file (*.txt):';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Value = '0.txt';
            app.PlotresultssinglerunCheckBox = uicheckbox(app.uipanel1);
            app.PlotresultssinglerunCheckBox.Text = ' Plot results (single run)';
            app.PlotresultssinglerunCheckBox.Value = true;
            app.runsimulationCheckBox = uicheckbox(app.uipanel1);
            app.runsimulationCheckBox.Text = ' run simulation';
            app.runsimulationCheckBox.Value = true;
            app.deleteResultsfolderexceptICfileCheckBox = uicheckbox(app.uipanel1);
            app.deleteResultsfolderexceptICfileCheckBox.Text = ' delete Results folder (except IC file)';
            app.deleteResultsfolderexceptICfileCheckBox.Value = true;
            app.OPTIONSButtonGroup = uibuttongroup(app.figure1);
            app.OPTIONSButtonGroup.Title = 'OPTIONS';
            app.Switch = uiswitch(app.OPTIONSButtonGroup, 'slider');
            app.Switch.Items = {'Single RUN', 'Sensitivity Analysis'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Single RUN';
            app.uipanel1_2 = uipanel(app.figure1);
            app.uipanel1_2.Title = 'if SENSITIVITY ANALYSIS (multiple runs)';
            app.scenariosEditFieldLabel = uilabel(app.uipanel1_2);
            app.scenariosEditFieldLabel.Text = '# scenarios';
            app.scenariosEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.scenariosEditField.Value = 200;
            app.A_D_maxEditFieldLabel = uilabel(app.uipanel1_2);
            app.A_D_maxEditFieldLabel.Text = 'A_D_max';
            app.A_D_maxEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.A_D_maxEditField.Value = 0.0003;
            app.ALPHA_IE_maxEditFieldLabel = uilabel(app.uipanel1_2);
            app.ALPHA_IE_maxEditFieldLabel.Text = 'ALPHA_IE_max';
            app.ALPHA_IE_maxEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.ALPHA_IE_maxEditField.Value = 3e-06;
            app.RuntestsCheckBox = uicheckbox(app.uipanel1_2);
            app.RuntestsCheckBox.Text = ' Run tests';
            app.RuntestsCheckBox.Value = true;
            app.AnalyzeresultsCheckBox = uicheckbox(app.uipanel1_2);
            app.AnalyzeresultsCheckBox.Text = 'Analyze results';
            app.AnalyzeresultsCheckBox.Value = true;
            app.PlotSensresultsCheckBox = uicheckbox(app.uipanel1_2);
            app.PlotSensresultsCheckBox.Text = 'Plot Sens results';
            app.PlotSensresultsCheckBox.Value = true;
            app.RUNButton = uibutton(app.figure1, 'state');
            app.RUNButton.Text = 'RUN';
            app.Lamp = uilamp(app.figure1);
            app.Lamp_2 = uilamp(app.figure1);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PULSE_GUI_run

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @PULSE_GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end